classdef Final_Reactores_juego < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PortadaTab                      matlab.ui.container.Tab
        elegircientifico                matlab.ui.control.Image
        elegirheroe                     matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        IniciodelaHistoriaTab           matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        Pg1Tab                          matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Pg2Tab                          matlab.ui.container.Tab
        EmpezarButton                   matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Mdulo1Tab                       matlab.ui.container.Tab
        COSTOSPanel                     matlab.ui.container.Panel
        RFPEditField                    matlab.ui.control.NumericEditField
        RFPEditFieldLabel               matlab.ui.control.Label
        RMCsEditField                   matlab.ui.control.NumericEditField
        RMCsEditFieldLabel              matlab.ui.control.Label
        inputpanel                      matlab.ui.container.Panel
        FaseSwitch                      matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        NmerodereactoresDropDown        matlab.ui.control.DropDown
        NroreactoresLabel               matlab.ui.control.Label
        XobjetivoEditField              matlab.ui.control.NumericEditField
        XobjetivoEditFieldLabel         matlab.ui.control.Label
        EscojoRFPButton                 matlab.ui.control.Button
        EvaluarRMCsButton               matlab.ui.control.Button
        ConversionesPanel               matlab.ui.container.Panel
        x3Label                         matlab.ui.control.Label
        x2Label                         matlab.ui.control.Label
        x1Label                         matlab.ui.control.Label
        EditFieldx3                     matlab.ui.control.NumericEditField
        EditFieldx2                     matlab.ui.control.NumericEditField
        EditFieldx1                     matlab.ui.control.NumericEditField
        Sliderx3                        matlab.ui.control.Slider
        Sliderx2                        matlab.ui.control.Slider
        Sliderx1                        matlab.ui.control.Slider
        Estrella3                       matlab.ui.control.Label
        Estrella1                       matlab.ui.control.Label
        Estrella2                       matlab.ui.control.Label
        Gauge                           matlab.ui.control.Gauge
        HighScoreLabel                  matlab.ui.control.Label
        panelgraficos                   matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Nivel1Tab                       matlab.ui.container.Tab
        Graficacaso1                    matlab.ui.control.UIAxes
        Nivel2Tab                       matlab.ui.container.Tab
        Graficacaso2                    matlab.ui.control.UIAxes
        Nivel3Tab                       matlab.ui.container.Tab
        Graficacaso3                    matlab.ui.control.UIAxes
        Nivel4Tab                       matlab.ui.container.Tab
        Graficacaso4                    matlab.ui.control.UIAxes
        ouputpanel                      matlab.ui.container.Panel
        SIGUIENTENIVELButton            matlab.ui.control.Button
        MensajeLabel                    matlab.ui.control.Label
        VOLMENESPanel                   matlab.ui.container.Panel
        V4EditField                     matlab.ui.control.NumericEditField
        V4EditFieldLabel                matlab.ui.control.Label
        TotalEditField                  matlab.ui.control.NumericEditField
        TotalEditFieldLabel             matlab.ui.control.Label
        V3EditField                     matlab.ui.control.NumericEditField
        V3EditFieldLabel                matlab.ui.control.Label
        V2EditField                     matlab.ui.control.NumericEditField
        V2EditFieldLabel                matlab.ui.control.Label
        RFPEditField_2                  matlab.ui.control.NumericEditField
        RFPEditField_2Label             matlab.ui.control.Label
        V1EditField                     matlab.ui.control.NumericEditField
        V1EditFieldLabel                matlab.ui.control.Label
        ContinuacinTab                  matlab.ui.container.Tab
        TabGroup3_2                     matlab.ui.container.TabGroup
        Pg3Tab                          matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Pg4Tab                          matlab.ui.container.Tab
        SiguienteButton                 matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        Mdulo2Tab                       matlab.ui.container.Tab
        SIGUIENTENIVELButton_2          matlab.ui.control.Button
        HighScoreLabel_2                matlab.ui.control.Label
        EvaluarButton                   matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        TabGroup2_2                     matlab.ui.container.TabGroup
        Nivel1Tab_2                     matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        Nivel2Tab_2                     matlab.ui.container.Tab
        UIAxes_8                        matlab.ui.control.UIAxes
        Nivel3Tab_2                     matlab.ui.container.Tab
        UIAxes_9                        matlab.ui.control.UIAxes
        Nivel4Tab_2                     matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        SALIDAPanel                     matlab.ui.container.Panel
        ExplicacionLabel                matlab.ui.control.Label
        ConversionAlcanzadaEditField    matlab.ui.control.NumericEditField
        ConversionAlcanzadaEditFieldLabel  matlab.ui.control.Label
        ENTRADAPanel                    matlab.ui.container.Panel
        FlujoVolumetricoEditField       matlab.ui.control.NumericEditField
        FlujoVolumetricoEditFieldLabel  matlab.ui.control.Label
        BajarButton                     matlab.ui.control.Button
        SubirButton                     matlab.ui.control.Button
        OrdenReactoresListBox           matlab.ui.control.ListBox
        OrdenReactoresListBoxLabel      matlab.ui.control.Label
        FinTab                          matlab.ui.container.Tab
        TabGroup3_3                     matlab.ui.container.TabGroup
        Pg5Tab                          matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        Pg6Tab                          matlab.ui.container.Tab
        Image2_3                        matlab.ui.control.Image
        Pg7Tab                          matlab.ui.container.Tab
        Image2_4                        matlab.ui.control.Image
    end


    properties (Access = public)

        CasosData
        CasoActual
        HighScore
        VidasModulo1
        VidasModulo2
        Estrella1_2
        Estrella2_2
        Estrella3_2
        Costo_RMCs
        Costo_RFP
        PersonajeSeleccionado
    end

    properties (Access = private)
        PermitirCambio = false;
    end

    methods (Access = private)
        function alertaSciFi(app, titulo, mensaje)

            figX = app.UIFigure.Position(1) + 340;
            figY = app.UIFigure.Position(2) + 250;
            figAlerta = uifigure('Position', [figX, figY, 400, 200], ...
                'Color', [0.05 0.08 0.14], ...
                'Name', titulo, 'Resize', 'off');


            uilabel(figAlerta, 'Text', mensaje, ...
                'FontColor', [0.00 0.94 1.00], 'FontWeight', 'bold', ...
                'Position', [20 70 360 100], 'FontSize', 14, ...
                'HorizontalAlignment', 'center', 'WordWrap', 'on');


            btn = uibutton(figAlerta, 'push', 'Text', 'CONFIRMAR [X]', ...
                'Position', [130 20 140 35], ...
                'BackgroundColor', [1.00 0.00 1.00], ... % Magenta
                'FontColor', 'w', 'FontWeight', 'bold', 'FontSize', 14);


            btn.ButtonPushedFcn = @(btn,event) delete(figAlerta);
        end
        function aplicarTemaGraficas(app)
            bg_fondo     = [0.05 0.08 0.14];
            neon_cian    = [0.00 0.94 1.00];
            neon_magenta = [1.00 0.00 1.00];

            ejes = [app.Graficacaso1, app.Graficacaso2, app.Graficacaso3, app.Graficacaso4, ...
                app.UIAxes_7, app.UIAxes_8, app.UIAxes_9, app.UIAxes_10];

            for i = 1:length(ejes)
                ejes(i).Color = bg_fondo;
                ejes(i).XColor = neon_cian;
                ejes(i).YColor = neon_cian;
                ejes(i).GridColor = neon_cian;
                ejes(i).GridAlpha = 0.2;
            end
            app.MensajeLabel.BackgroundColor = bg_fondo;
            app.MensajeLabel.FontColor = neon_cian;
            app.MensajeLabel.FontWeight = 'bold';
            app.HighScoreLabel.FontColor = neon_magenta;
            app.HighScoreLabel_2.FontColor = neon_magenta;
            app.Gauge.ScaleColors = [
                1.00 0.00 0.00;
                1.00 0.80 0.00; %
                0.20 1.00 0.20
                ];
            app.Gauge.ScaleColorLimits = [0 40; 40 75; 75 100];
            app.Estrella1.FontColor = neon_cian;
            app.Estrella2.FontColor = neon_cian;
            app.Estrella3.FontColor = neon_cian;
        end
        function actualizarTodo(app)
            n = str2double(app.NmerodereactoresDropDown.Value);
            Xobj = app.XobjetivoEditField.Value;
            X_ints = [];

            if n >= 2, X_ints(1) = app.Sliderx1.Value; end
            if n >= 3, X_ints(2) = app.Sliderx2.Value; end
            if n >= 4, X_ints(3) = app.Sliderx3.Value; end


            valido = true;
            if n >= 2 && (X_ints(1) >= Xobj)
                valido = false;
                mensajeError = '⚠️ Error: X1 debe ser menor que Xobj';
            elseif n >= 3 && (X_ints(2) >= Xobj || X_ints(2) <= X_ints(1))
                valido = false;
                mensajeError = '⚠️ Error: X2 debe ser > X1 y < Xobj';
            elseif n >= 4 && (X_ints(3) >= Xobj || X_ints(3) <= X_ints(2))
                valido = false;
                mensajeError = '⚠️ Error: X3 debe ser > X2 y < Xobj';
            end

            if ~valido
                app.MensajeLabel.Text = mensajeError;
                app.MensajeLabel.FontColor = [1 0 0];
                app.MensajeLabel.FontWeight = 'bold';
                app.MensajeLabel.BackgroundColor = [1 0.9 0.9];

                app.Estrella1.Text = '☆';
                app.Estrella2.Text = '☆';
                app.Estrella3.Text = '☆';


                app.EvaluarRMCsButton.Enable = 'off';
                app.EscojoRFPButton.Enable = 'off';

                return;
            else
                app.MensajeLabel.FontColor = [0 0.5 0];
                app.MensajeLabel.FontWeight = 'normal';
                app.MensajeLabel.BackgroundColor = [0.9 1 0.9];

                app.EvaluarRMCsButton.Enable = 'on';
                app.EscojoRFPButton.Enable = 'on';
            end


            V_RMCs = zeros(1, n);
            X_prev = 0;
            for i = 1:n
                if i < n
                    Xi = X_ints(i);
                else
                    Xi = Xobj;
                end
                V_RMCs(i) = (Xi - X_prev) * app.evaluarFX(Xi);
                X_prev = Xi;
            end
            VolTotalRMC = sum(V_RMCs);
            X_fino = linspace(0, Xobj, 200);
            FX_fino = app.evaluarFX(X_fino);
            VolRFP = trapz(X_fino, FX_fino);


            app.Costo_RMCs = 0;
            for i = 1:n
                app.Costo_RMCs = app.Costo_RMCs + app.costo_RMC(V_RMCs(i));
            end
            app.Costo_RFP = app.costo_RFP(VolRFP);


            if app.Costo_RFP > 0
                eficiencia = (app.Costo_RFP / app.Costo_RMCs) * 100;

                eficiencia = max(0, min(eficiencia, 100));

                app.Gauge.Value = eficiencia;
            end


            if n >= 1, app.V1EditField.Value = V_RMCs(1); else, app.V1EditField.Value = 0; end
            if n >= 2, app.V2EditField.Value = V_RMCs(2); else, app.V2EditField.Value = 0; end
            if n >= 3, app.V3EditField.Value = V_RMCs(3); else, app.V3EditField.Value = 0; end
            if n >= 4, app.V4EditField.Value = V_RMCs(4); else, app.V4EditField.Value = 0; end


            app.TotalEditField.Value = VolTotalRMC;
            app.RFPEditField_2.Value=VolRFP;


            app.RFPEditField.Value = 0;
            app.RMCsEditField.Value = 0;
            app.dibujarLevenspiel(X_ints, Xobj, V_RMCs, VolRFP);


        end
        function costo = costo_RMC(app, V)
            CF_RMC = 3000;
            CV_RMC = 800;
            alfa = 0.6;
            costo = CF_RMC + CV_RMC * (V.^alfa);
        end

        function costo = costo_RFP(app, V)
            CF_RFP = 1200;
            CV_RFP = 1000;
            alfa = 0.6;
            costo = CF_RFP + CV_RFP * (V.^alfa);
        end


        function y = evaluarFX(app, x)
            datos = app.CasosData(app.CasoActual);

            y = interp1(datos.X, datos.FX, x, 'linear', 'extrap');


            if app.CasoActual == 4 && strcmp(app.FaseSwitch.Value, 'Gas')
                y = y .* (1 + datos.epsilon * x);
            end
        end


        function dibujarLevenspiel(app, X_ints, Xobj, V_RMCs, VolRFP)
            switch app.CasoActual
                case 1, ax = app.Graficacaso1;
                case 2, ax = app.Graficacaso2;
                case 3, ax = app.Graficacaso3;
                case 4, ax = app.Graficacaso4;
            end
            cla(ax);
            datos = app.CasosData(app.CasoActual);
            n = length(V_RMCs);

            X_fill = linspace(0, Xobj, 100);
            Y_fill = app.evaluarFX(X_fill);
            fill(ax, [X_fill, Xobj, 0], [Y_fill, 0, 0], [0.2 1.0 0.2], ...
                'FaceAlpha', 0.25, 'EdgeColor', 'none');
            hold(ax, 'on');
            X_plot = linspace(0, max(1, Xobj*1.1), 100);
            Y_plot = app.evaluarFX(X_plot);

            plot(ax, X_plot, Y_plot, '-', 'Color', [1.0 0.0 1.0], 'LineWidth', 3);


            colores = [
                0.00 0.94 1.00;
                0.20 1.00 0.20;
                1.00 0.00 1.00;
                0.90 0.95 1.00
                ];

            X_prev = 0;
            for i = 1:n
                if i < n
                    Xi = X_ints(i);
                else
                    Xi = Xobj;
                end
                Yi = app.evaluarFX(Xi);
                X_rect = [X_prev, Xi, Xi, X_prev, X_prev];
                Y_rect = [0, 0, Yi, Yi, 0];

                fill(ax, X_rect, Y_rect, colores(i,:), 'FaceAlpha', 0.2, 'EdgeColor', colores(i,:), 'LineWidth', 2);
                plot(ax, Xi, Yi, 'o', 'MarkerFaceColor', colores(i,:), 'MarkerEdgeColor', 'w', 'MarkerSize', 8);

                X_prev = Xi;
            end

            xline(ax, Xobj, 'r--', 'LineWidth', 1.5);

            hold(ax, 'off');
            xlim(ax, [0 max(1, Xobj*1.1)]);
            ylim(ax, [0 max(Y_fill)*1.2]);
            ylabel(ax, 'F_{A0}/(-r_A)', 'FontWeight', 'bold', 'Color', [0.00 0.94 1.00]);
            xlabel(ax, 'Conversión X', 'FontWeight', 'bold', 'Color', [0.00 0.94 1.00]);
            title(ax, sprintf('Diagrama de Levenspiel - %d RMC en serie', n), 'Color', [0.90 0.95 1.00]);
            grid(ax, 'on');
        end


        function rA = evaluarCineticarA(app, CA)


            tabSeleccionada = app.TabGroup2_2.SelectedTab.Title;


            switch tabSeleccionada
                case 'Nivel 1'
                    k = 6.8;
                    rA = k * CA.^2;

                case 'Nivel 2'

                    k = 0.5;
                    rA = k * CA .* (1.05 - CA);

                case 'Nivel 3'

                    rA = (0.05 .* CA) ./ (1.2 + 55 .* CA.^2.5);

                case 'Nivel 4'

                    rA = CA;
                otherwise
                    rA = 6.8 * CA.^2;
            end


            rA(rA <= 0) = 1e-6;

        end


        function CA_out = calcularCAout_RMC(app, tau, CA_in)

            funcionError = @(CA) abs(app.evaluarCineticarA(CA) - (1/tau) * (CA_in - CA));


            CA_out = fminbnd(funcionError, 0, CA_in);
        end


        function actualizarModulo2(app)
            v0 = app.FlujoVolumetricoEditField.Value;

            if v0 <= 0
                v0 = 1;
                app.FlujoVolumetricoEditField.Value = v0;
            elseif v0 > 10
                v0 = 10;
                app.FlujoVolumetricoEditField.Value = v0;
            end

            CA0 = 1;


            items_lista = app.OrdenReactoresListBox.Items;
            n = length(items_lista);
            V_ordenados = zeros(1, n);

            for i = 1:n
                numStr = regexp(items_lista{i}, '\d+', 'match');
                if ~isempty(numStr)
                    V_ordenados(i) = str2double(numStr{1});
                end
            end


            tau_ordenados = V_ordenados / v0;

            CA_ints = zeros(1, n);
            rA_ints = zeros(1, n);

            CA_in = CA0;
            for i = 1:n
                CA_out = app.calcularCAout_RMC(tau_ordenados(i), CA_in);
                CA_ints(i) = CA_out;
                rA_ints(i) = app.evaluarCineticarA(CA_out);
                CA_in = CA_out;
            end

            if n > 0
                CA_final = CA_ints(end);
                X_final = 1 - (CA_final / CA0);
                if X_final < 0; X_final = 0; end
                app.ConversionAlcanzadaEditField.Value = X_final * 100; % Asumo que lo muestras en %
            else
                app.ConversionAlcanzadaEditField.Value = 0;
            end


            tabSeleccionada = app.TabGroup2_2.SelectedTab.Title;
            switch tabSeleccionada
                case 'Nivel 1', ax = app.UIAxes_7;
                case 'Nivel 2', ax = app.UIAxes_8;
                case 'Nivel 3', ax = app.UIAxes_9;
                case 'Nivel 4', ax = app.UIAxes_10;
                otherwise, ax = app.UIAxes_7;
            end

            cla(ax);
            hold(ax, 'on');


            CA_plot = linspace(0, max(1, CA0*1.1), 100);
            rA_plot = app.evaluarCineticarA(CA_plot);
            fill(ax, [CA_plot, max(CA_plot), 0], [rA_plot, 0, 0], [0.85 0.85 0.85], ...
                'FaceAlpha', 0.5, 'EdgeColor', 'none');
            plot(ax, CA_plot, rA_plot, 'c-', 'LineWidth', 2.5);

            colores = [
                0.4 0.8 0.4; % Verde
                0.9 0.8 0.3; % Amarillo
                0.9 0.5 0.3; % Naranja
                0.7 0.4 0.8  % Morado
                ];


            CA_prev = CA0;

            for i = 1:n

                X_tri = [CA_prev, CA_ints(i), CA_ints(i), CA_prev];
                Y_tri = [0, rA_ints(i), 0, 0];
                fill(ax, X_tri, Y_tri, colores(i,:), 'FaceAlpha', 0.6, 'EdgeColor', 'none');


                plot(ax, [CA_prev, CA_ints(i)], [0, rA_ints(i)], '-', 'Color', colores(i,:), 'LineWidth', 1.5);


                plot(ax, [CA_ints(i), CA_ints(i)], [rA_ints(i), 0], '--', 'Color', colores(i,:), 'LineWidth', 1.5);


                plot(ax, CA_ints(i), rA_ints(i), 'ko', 'MarkerFaceColor', colores(i,:), 'MarkerSize', 6);


                x_text = (CA_prev + CA_ints(i) + CA_ints(i)) / 3;
                y_text = (0 + rA_ints(i) + 0) / 3;

                texto_reactor = strrep(items_lista{i}, 'RMC ', '');
                texto_reactor = strrep(texto_reactor, 'Tanque ', '');

                text(ax, x_text, y_text, texto_reactor, 'HorizontalAlignment', 'center', ...
                    'FontWeight', 'bold', 'FontSize', 9, 'Color', 'k');

                CA_prev = CA_ints(i);
            end


            xlim(ax, [0 max(1, CA0*1.1)]);
            ylim(ax, [0 max(rA_plot)*1.2]);

            ylabel(ax, 'Velocidad de Reacción (-r_A)', 'FontWeight', 'bold');
            xlabel(ax, 'Concentración del Reactante (C_A)', 'FontWeight', 'bold');
            title(ax, sprintf('Diseño Gráfico de %d RMC en Serie', n));
            grid(ax, 'on');

            hold(ax, 'off');
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.CasosData = struct();
            app.Estrella1.Text = '☆';
            app.Estrella2.Text = '☆';
            app.Estrella3.Text = '☆';
            app.SIGUIENTENIVELButton.Enable='off';
            app.SIGUIENTENIVELButton_2.Enable='off';


            app.VidasModulo1 = 3;
            app.VidasModulo2 = 3;
            app.HighScoreLabel.Text = ' ❤️❤️❤️';
            app.HighScoreLabel_2.Text = ' ❤️❤️❤️';
            app.HighScore = 0;

            app.MensajeLabel.Text = '💡 Ajusta los sliders para diseñar tu reactor.';
            app.MensajeLabel.FontColor = [0 0 0.8];
            app.MensajeLabel.FontWeight = 'bold';
            app.MensajeLabel.BackgroundColor = [0.9 0.9 1];


            X1 = [0; 0.1; 0.2; 0.4; 0.6; 0.7; 0.8];
            rA1 = [0.45; 0.37; 0.30; 0.195; 0.113; 0.079; 0.05];
            app.CasosData(1).X = X1;
            app.CasosData(1).FX = 1 ./ rA1;
            app.CasosData(1).nombre = 'Curva Ascendente';
            app.CasosData(1).Xobj = 0.75;
            app.CasosData(1).epsilon = 0;


            X2 = [0; 0.2; 0.4; 0.6; 0.65];
            rA2 = [39; 53; 59; 38; 25];
            app.CasosData(2).X = X2;
            app.CasosData(2).FX = 1 ./ rA2;
            app.CasosData(2).nombre = 'Curva con Mínimo';
            app.CasosData(2).Xobj = 0.6;
            app.CasosData(2).epsilon = 0;


            X3 = linspace(0, 1, 50)';
            CA0 = 1;
            CA = CA0 * (1 - X3);
            rA3 = 0.01 * (CA ./ (1 + 30 * CA.^2));
            app.CasosData(3).X = X3;
            app.CasosData(3).FX = 1 ./ rA3;
            app.CasosData(3).nombre = 'Curva con Inhibición';
            app.CasosData(3).Xobj = 0.90;
            app.CasosData(3).epsilon = 0;


            X4 = linspace(0, 1, 100)';
            app.CasosData(4).X = X4;
            app.CasosData(4).FX = 20000 * (X4 - 0.4).^2 + 300;
            app.CasosData(4).nombre = 'Curva Parabólica (Modo Gas disponible)';
            app.CasosData(4).Xobj = 0.65;
            app.CasosData(4).epsilon = 0.5;


            app.CasoActual = 1;
            app.XobjetivoEditField.Value = app.CasosData(1).Xobj;
            app.Sliderx1.Value = 0.4; app.EditFieldx1.Value = 0.4;
            app.Sliderx2.Value = 0.5; app.EditFieldx2.Value = 0.5;
            app.Sliderx3.Value = 0.55; app.EditFieldx3.Value = 0.55;

            app.V1EditField.Editable = false; app.V2EditField.Editable = false;
            app.V3EditField.Editable = false; app.V4EditField.Editable = false;
            app.TotalEditField.Editable = false; app.RMCsEditField.Editable = false;

            app.FaseSwitch.Enable = 'off';
            app.OrdenReactoresListBox.Items = {'RMC 1 L', 'RMC 4 L', 'RMC 10 L'};
            app.OrdenReactoresListBox.Value = 'RMC 1 L';
            app.FlujoVolumetricoEditField.Value = 10;

            app.actualizarTodo();
            app.actualizarModulo2();
            app.aplicarTemaGraficas();

        end

        % Value changed function: NmerodereactoresDropDown
        function NmerodereactoresDropDownValueChanged(app, event)
            valorSeleccionado = event.Value;

            app.EditFieldx1.Visible = 'off';
            app.Sliderx1.Visible = 'off';
            app.x1Label.Visible = 'off';

            app.EditFieldx2.Visible = 'off';
            app.Sliderx2.Visible = 'off';
            app.x2Label.Visible = 'off';

            app.EditFieldx3.Visible = 'off';
            app.Sliderx3.Visible = 'off';
            app.x3Label.Visible = 'off';

            switch valorSeleccionado
                case '1'
                    app.ConversionesPanel.Visible = 'off';

                case '2'
                    app.ConversionesPanel.Visible = 'on';

                    app.EditFieldx1.Visible = 'on';
                    app.Sliderx1.Visible = 'on';
                    app.x1Label.Visible = 'on';

                case '3'
                    app.ConversionesPanel.Visible = 'on';

                    app.EditFieldx1.Visible = 'on';
                    app.Sliderx1.Visible = 'on';
                    app.x1Label.Visible = 'on';

                    app.EditFieldx2.Visible = 'on';
                    app.Sliderx2.Visible = 'on';
                    app.x2Label.Visible = 'on';

                case '4'
                    app.ConversionesPanel.Visible = 'on';

                    app.EditFieldx1.Visible = 'on';
                    app.Sliderx1.Visible = 'on';
                    app.x1Label.Visible = 'on';

                    app.EditFieldx2.Visible = 'on';
                    app.Sliderx2.Visible = 'on';
                    app.x2Label.Visible = 'on';

                    app.EditFieldx3.Visible = 'on';
                    app.Sliderx3.Visible = 'on';
                    app.x3Label.Visible = 'on';
            end
            app.actualizarTodo();
        end

        % Value changed function: Sliderx1
        function Sliderx1ValueChanged(app, event)
            app.EditFieldx1.Value = app.Sliderx1.Value;
            app.actualizarTodo();
        end

        % Value changed function: Sliderx2
        function Sliderx2ValueChanged(app, event)
            app.EditFieldx2.Value = app.Sliderx2.Value;
            app.actualizarTodo();
        end

        % Value changed function: Sliderx3
        function Sliderx3ValueChanged(app, event)
            app.EditFieldx3.Value = app.Sliderx3.Value;
            app.actualizarTodo();
        end

        % Value changed function: EditFieldx1
        function EditFieldx1ValueChanged(app, event)
            valor = app.EditFieldx1.Value;

            if valor < 0
                valor = 0;
                app.EditFieldx1.Value = 0;
            elseif valor > 1
                valor = 1;
                app.EditFieldx1.Value = 1;
            end

            app.Sliderx1.Value = valor;
            app.actualizarTodo();
        end

        % Value changed function: EditFieldx2
        function EditFieldx2ValueChanged(app, event)
            valor = app.EditFieldx2.Value;
            if valor < 0, valor = 0; app.EditFieldx2.Value = 0; end
            if valor > 1, valor = 1; app.EditFieldx2.Value = 1; end
            app.Sliderx2.Value = valor;
            app.actualizarTodo();
        end

        % Value changed function: EditFieldx3
        function EditFieldx3ValueChanged(app, event)
            valor = app.EditFieldx3.Value;
            if valor < 0, valor = 0; app.EditFieldx3.Value = 0; end
            if valor > 1, valor = 1; app.EditFieldx3.Value = 1; end
            app.Sliderx3.Value = valor;
            app.actualizarTodo();
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)

            if ~app.PermitirCambio

                app.PermitirCambio = true;
                app.TabGroup2.SelectedTab = event.OldValue;
                app.PermitirCambio = false;


                app.alertaSciFi('ACCESO DENEGADO', 'Nivel bloqueado. Debes superar el nivel actual y usar el botón de Siguiente Nivel para avanzar.');
                return; % Cortamos la función
            end


            app.PermitirCambio = false;

            selectedTab = app.TabGroup2.SelectedTab;
            caseXobj = 0;


            app.VidasModulo1 = 3;
            app.HighScoreLabel.Text = ' ❤️❤️❤️';

            app.Estrella1.Text = '☆';
            app.Estrella2.Text = '☆';
            app.Estrella3.Text = '☆';


            app.Sliderx1.Enable = 'on';
            app.Sliderx2.Enable = 'on';
            app.Sliderx3.Enable = 'on';
            app.EvaluarRMCsButton.Enable = 'on';
            app.EscojoRFPButton.Enable = 'on';

            app.MensajeLabel.Text = '💡 Ajusta los sliders para diseñar tu reactor.';
            app.MensajeLabel.FontColor = [0 0 0.8];
            app.MensajeLabel.BackgroundColor = [0.9 0.9 1];


            tituloLimpio = strtrim(selectedTab.Title); % Limpiamos espacios ocultos
            switch tituloLimpio
                case 'Nivel 1', app.CasoActual = 1; caseXobj = app.CasosData(1).Xobj;
                case 'Nivel 2', app.CasoActual = 2; caseXobj = app.CasosData(2).Xobj;
                case 'Nivel 3', app.CasoActual = 3; caseXobj = app.CasosData(3).Xobj;
                case 'Nivel 4', app.CasoActual = 4; caseXobj = app.CasosData(4).Xobj;
            end


            if app.CasoActual == 4
                app.FaseSwitch.Enable = 'on';
            else
                app.FaseSwitch.Value = 'Líq.'; % Forzar a líquido
                app.FaseSwitch.Enable = 'off'; % Bloquear
            end


            mensajeNivel = sprintf('INICIALIZANDO...\nObjetivo de conversión requerido: X = %.2f', caseXobj);
            app.alertaSciFi(sprintf('SISTEMA: NIVEL %d', app.CasoActual), mensajeNivel);

            app.XobjetivoEditField.Value = caseXobj;
            app.MensajeLabel.Text = sprintf('Nivel %d | Objetivo: X = %.2f', app.CasoActual, caseXobj);

            try
                app.actualizarTodo();
            catch ME

                mensajeFallo = sprintf('Error real:\n%s\nLínea: %d', ME.message, ME.stack(1).line);
                app.alertaSciFi('ERROR CRÍTICO DETECTADO', mensajeFallo);
                disp(ME.getReport()); % También lo escupimos en la consola
            end
        end

        % Value changed function: FaseSwitch
        function FaseSwitchValueChanged(app, event)
            if strcmp(app.FaseSwitch.Value, 'Gas')
                app.alertaSciFi('SISTEMA: FASE GAS', 'Expansión volumétrica (ε) detectada. Recalibrando parámetros de simulación.');
                app.MensajeLabel.Text = 'Modo gas: expansión volumétrica activada.';
                app.MensajeLabel.FontColor = [0.8 0.4 0];
                app.MensajeLabel.BackgroundColor = [1 0.9 0.8];
            else
                uialert(app.UIFigure, ...
                    'Modo líquido activado. Se asume densidad constante (ε = 0).', ...
                    'Cambio de fase', ...
                    'Icon', 'info');
                app.MensajeLabel.Text = 'Modo líquido: densidad constante.';
                app.MensajeLabel.FontColor = [0 0 0.8];
                app.MensajeLabel.BackgroundColor = [0.9 0.9 1];
            end
            app.actualizarTodo();
        end

        % Value changed function: OrdenReactoresListBox
        function OrdenReactoresListBoxValueChanged(app, event)
            app.actualizarModulo2();
        end

        % Button pushed function: SubirButton
        function SubirButtonPushed(app, event)
            lista = app.OrdenReactoresListBox.Items;
            val = app.OrdenReactoresListBox.Value;
            idx = find(strcmp(lista, val));

            if idx > 1
                temp = lista{idx-1};
                lista{idx-1} = lista{idx};
                lista{idx} = temp;
                app.OrdenReactoresListBox.Items = lista;
                app.OrdenReactoresListBox.Value = val;
                app.actualizarModulo2();
            end
        end

        % Button pushed function: BajarButton
        function BajarButtonPushed(app, event)
            lista = app.OrdenReactoresListBox.Items;
            val = app.OrdenReactoresListBox.Value;
            idx = find(strcmp(lista, val));

            if idx < length(lista)
                temp = lista{idx+1};
                lista{idx+1} = lista{idx};
                lista{idx} = temp;
                app.OrdenReactoresListBox.Items = lista;
                app.OrdenReactoresListBox.Value = val;
                app.actualizarModulo2();
            end
        end

        % Value changed function: FlujoVolumetricoEditField
        function FlujoVolumetricoEditFieldValueChanged(app, event)
            valor = app.FlujoVolumetricoEditField.Value;

            if valor <= 0
                valor = 1;
                app.FlujoVolumetricoEditField.Value = valor;
                uialert(app.UIFigure, 'El flujo volumétrico debe ser estrictamente mayor a 0.', 'Valor inválido', 'Icon', 'warning');

            elseif valor > 10

                valor = 10;
                app.FlujoVolumetricoEditField.Value = valor;
                uialert(app.UIFigure, 'El flujo volumétrico máximo permitido es 10 L/min.', 'Límite excedido', 'Icon', 'warning');
            end


            app.actualizarModulo2();
        end

        % Selection change function: TabGroup2_2
        function TabGroup2_2SelectionChanged(app, event)
            if ~app.PermitirCambio

                app.PermitirCambio = true;
                app.TabGroup2_2.SelectedTab = event.OldValue;
                app.PermitirCambio = false;


                app.alertaSciFi('ACCESO DENEGADO', 'Nivel bloqueado. Supera el nivel actual para avanzar.');
                return; % Cortamos la función aquí para no cargar el nivel
            end


            app.PermitirCambio = false;


            tabSeleccionada = app.TabGroup2_2.SelectedTab.Title;


            app.VidasModulo2 = 3;
            app.HighScoreLabel_2.Text = ' ❤️❤️❤️';


            if isprop(app, 'Estrella1_2')
                app.Estrella1_2.Text = '☆';
                app.Estrella2_2.Text = '☆';
                app.Estrella3_2.Text = '☆';
            end


            app.SubirButton.Enable = 'on';
            app.BajarButton.Enable = 'on';
            if isprop(app, 'EvaluarButton')
                app.EvaluarButton.Enable = 'on';
            end


            app.ExplicacionLabel.Text = '';


            switch tabSeleccionada
                case 'Nivel 1' % E1 S4: 2do Orden
                    app.OrdenReactoresListBox.Items = {'RMC 1 L', 'RMC 4 L', 'RMC 10 L'};
                    app.OrdenReactoresListBox.Value = 'RMC 10 L';
                    app.alertaSciFi('MISIÓN TÁCTICA 1', {'Cinética de 2do Orden detectada.', '¿Cuál reactor debe ir primero para maximizar la conversión?'});

                case 'Nivel 2' % E4 S5: Autocatalítica
                    app.OrdenReactoresListBox.Items = {'RMC 30 L', 'RMC 100 L'};
                    app.OrdenReactoresListBox.Value = 'RMC 30 L';
                    app.alertaSciFi('MISIÓN TÁCTICA 2', {'Alerta: Reacción Autocatalítica.', 'Identifique el máximo de la curva para optimizar el orden.'});

                case 'Nivel 3' % E1 S5: Compleja
                    app.OrdenReactoresListBox.Items = {'RMC 100 L', 'RMC 300 L', 'RMC 600 L'};
                    app.OrdenReactoresListBox.Value = 'RMC 300 L';
                    app.alertaSciFi('MISIÓN TÁCTICA 3', {'Cinética Compleja.', 'El volumen mínimo requiere un uso estratégico de los RMC.'});

                case 'Nivel 4' % Fase Gas obligatoria
                    app.OrdenReactoresListBox.Items = {'Tanque 10 L', 'Tanque 20 L', 'Tanque 40 L'};
                    app.OrdenReactoresListBox.Value = 'Tanque 10 L';
                    app.alertaSciFi('MISIÓN TÁCTICA 4', {'Fase Gas con Expansión (ε = 0.5).', 'La curva ascenderá a velocidad crítica.'});
            end


            app.actualizarModulo2();
        end

        % Button pushed function: EvaluarRMCsButton
        function EvaluarRMCsButtonPushed(app, event)
            if app.VidasModulo1 <= 0
                app.alertaSciFi('ADVERTENCIA CRÍTICA', 'Integridad del sistema comprometida. Sin intentos restantes. Reinicie el nivel.');
                return;
            end

            CostoRMCs = app.Costo_RMCs;
            CostoRFP = app.Costo_RFP;
            app.RMCsEditField.Value = CostoRMCs;
            app.RFPEditField.Value = CostoRFP;

            if CostoRMCs <= 0.65 * CostoRFP
                estrellas = 3;
                mensaje = '🏆 ¡Diseño Maestro! Redujiste el costo un 65% o más';
            elseif CostoRMCs <= 0.80 * CostoRFP
                estrellas = 2;
                mensaje = '👍 ¡Gran diseño! Ahorro significativo frente al RFP.';
            elseif CostoRMCs <= CostoRFP
                estrellas = 1;
                mensaje = '✔️ Diseño aceptable. Mejor o igual que el RFP, pero se puede optimizar.';
            else
                estrellas = 0;
                mensaje = '❌ El diseño RMC es más costoso que el RFP. Pierdes 1 vida.';
            end

            if estrellas == 0
                app.VidasModulo1 = app.VidasModulo1 - 1;
            end

            corazones = repmat('❤️', 1, app.VidasModulo1);
            calaveras = repmat('💀', 1, 3 - app.VidasModulo1);
            app.HighScoreLabel.Text = [corazones calaveras];


            app.Estrella1.Text = '★'; app.Estrella2.Text = '★'; app.Estrella3.Text = '★';
            if estrellas < 3, app.Estrella3.Text = '☆'; end
            if estrellas < 2, app.Estrella2.Text = '☆'; end
            if estrellas < 1, app.Estrella1.Text = '☆'; end

            if app.VidasModulo1 <= 0
                app.MensajeLabel.Text = '💀 GAME OVER.';
                app.MensajeLabel.FontColor = [1 1 1];
                app.MensajeLabel.BackgroundColor = [0.8 0 0];


                app.Sliderx1.Enable = 'off'; app.Sliderx2.Enable = 'off'; app.Sliderx3.Enable = 'off';
                app.EvaluarRMCsButton.Enable = 'off'; app.EscojoRFPButton.Enable = 'off';

                uialert(app.UIFigure, 'Fuiste Capturado. El Dr. Caos ha reinado.', 'GAME OVER', 'Icon', 'error');
            else
                app.MensajeLabel.Text = mensaje;
                if estrellas > 0
                    app.MensajeLabel.FontColor = [0 0.5 0];
                    app.MensajeLabel.BackgroundColor = [0.9 1 0.9];


                    app.SIGUIENTENIVELButton.Enable = 'on';
                    app.alertaSciFi('DISEÑO MAESTRO APROBADO', {'¡Ingeniería sobresaliente!', 'Has calibrado los volúmenes de la red RMC logrando una rentabilidad superior al sistema base.'});
                else
                    app.MensajeLabel.FontColor = [0.8 0 0];
                    app.MensajeLabel.BackgroundColor = [1 0.9 0.9];


                    app.SIGUIENTENIVELButton.Enable = 'off';
                end

            end

        end

        % Button pushed function: EscojoRFPButton
        function EscojoRFPButtonPushed(app, event)

            CostoRMCs = app.Costo_RMCs;
            CostoRFP = app.Costo_RFP;

            app.RMCsEditField.Value = CostoRMCs;
            app.RFPEditField.Value = CostoRFP;


            casosDondeRFPGana = [1, 2];
            if ismember(app.CasoActual, casosDondeRFPGana)
                app.MensajeLabel.Text = '🏆 ¡Correcto! El RFP es invencible en esta cinética.';
                app.MensajeLabel.FontColor = [0 0.5 0];
                app.Estrella1.Text = '★'; app.Estrella2.Text = '★'; app.Estrella3.Text = '★';
                app.Sliderx1.Enable = 'off'; app.Sliderx2.Enable = 'off'; app.Sliderx3.Enable = 'off';
                app.EvaluarRMCsButton.Enable = 'off'; app.EscojoRFPButton.Enable = 'off';
                app.alertaSciFi('ESTRATEGIA ÓPTIMA [RFP]', {'¡Excelente deducción táctica!', 'En esta cinética, el área geométrica de los RMC jamás superará la eficiencia de integración del RFP.'});
                app.SIGUIENTENIVELButton.Enable='on';

            else
                app.MensajeLabel.Text = '❌ Te precipitaste. Los RMCs pueden ganar aquí.';
                app.MensajeLabel.FontColor = [0.8 0 0];
                app.VidasModulo1 = app.VidasModulo1 - 1;

                corazones = repmat('❤️', 1, app.VidasModulo1);
                calaveras = repmat('💀', 1, 3 - app.VidasModulo1);
                app.HighScoreLabel.Text = [corazones calaveras];

                if app.VidasModulo1 <= 0
                    app.Sliderx1.Enable = 'off'; app.Sliderx2.Enable = 'off'; app.Sliderx3.Enable = 'off';
                    app.EvaluarRMCsButton.Enable = 'off'; app.EscojoRFPButton.Enable = 'off';
                    uialert(app.UIFigure, 'Fuiste Capturado El Dr.Caos ha reinado', 'GAME OVER', 'Icon', 'error');
                end
            end
        end

        % Button pushed function: EvaluarButton
        function EvaluarButtonPushed(app, event)
            if app.VidasModulo2 <= 0
                uialert(app.UIFigure, 'No te quedan vidas en este módulo.', 'GAME OVER', 'Icon', 'error');
                return;
            end

            tabSeleccionada = app.TabGroup2_2.SelectedTab.Title;
            switch tabSeleccionada
                case 'Nivel 1'
                    ordenOptimo = {'RMC 1 L', 'RMC 4 L', 'RMC 10 L'};
                    explicacion = 'Como rA cae bruscamente al bajar CA, ir de Menor a Mayor volumen permite aprovechar las altas velocidades iniciales sin diluir la mezcla de golpe.';
                case 'Nivel 2'
                    ordenOptimo = {'RMC 100 L', 'RMC 30 L'};
                    explicacion = 'La curva rA vs CA tiene un máximo. Empezar con el reactor Mayor te permite saltar la zona inicial lenta y llegar a la máxima velocidad.';
                case 'Nivel 3'
                    ordenOptimo = {'RMC 600 L', 'RMC 300 L', 'RMC 100 L'};
                    explicacion = 'rA empieza baja y tiene un pico. El orden Grande -> Mediano -> Pequeño garantiza llegar rápido a ese pico y luego aprovechar el resto de la reacción.';
                case 'Nivel 4'
                    ordenOptimo = {'Tanque 10 L', 'Tanque 20 L', 'Tanque 40 L'};
                    explicacion = 'Al expandirse el gas, el flujo aumenta. Ir de Menor a Mayor volumen da espacio al gas expandido al final, manteniendo un buen tiempo de residencia.';
            end

            ordenActual = app.OrdenReactoresListBox.Items;
            X_usuario = app.ConversionAlcanzadaEditField.Value;

            if isequal(ordenActual, ordenOptimo)

                app.Estrella1_2.Text = '★'; app.Estrella2_2.Text = '★'; app.Estrella3_2.Text = '★';
                app.SubirButton.Enable = 'off'; app.BajarButton.Enable = 'off'; app.EvaluarButton.Enable = 'off';
                app.ExplicacionLabel.Text = explicacion;
                app.ExplicacionLabel.FontColor = [0, 0.5, 0]; % Verde oscuro

                mensajeVictoria = sprintf('¡Secuencia Óptima!\nMáxima conversión teórica (%.2f%%).', X_usuario);
                app.alertaSciFi('NIVEL SUPERADO', mensajeVictoria);
                app.SIGUIENTENIVELButton_2.Enable='on';
            else

                app.Estrella1_2.Text = '☆'; app.Estrella2_2.Text = '☆'; app.Estrella3_2.Text = '☆';
                app.VidasModulo2 = app.VidasModulo2 - 1;

                corazones = repmat('❤️', 1, app.VidasModulo2);
                calaveras = repmat('💀', 1, 3 - app.VidasModulo2);
                app.HighScoreLabel_2.Text = [corazones calaveras];

                if app.VidasModulo2 <= 0
                    app.SubirButton.Enable = 'off'; app.BajarButton.Enable = 'off'; app.EvaluarButton.Enable = 'off';
                    app.alertaSciFi('GAME OVER', 'Secuencia incorrecta repetidas veces. GAME OVER.');
                else
                    app.alertaSciFi('INTÉNTALO DE NUEVO', 'Orden incorrecto. Pierdes 1 vida.');
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if strcmp(selectedTab.Title, 'Módulo 1 ')
                app.VidasModulo1 = 3;
                app.HighScoreLabel.Text = ' ❤️❤️❤️';

                app.Sliderx1.Enable = 'on'; app.Sliderx2.Enable = 'on'; app.Sliderx3.Enable = 'on';
                app.EvaluarRMCsButton.Enable = 'on'; app.EscojoRFPButton.Enable = 'on';
                app.NmerodereactoresDropDown.Enable = 'on';

            elseif strcmp(selectedTab.Title, 'Módulo 2 ')
                app.VidasModulo2 = 3;
                app.HighScoreLabel_2.Text = ' ❤️❤️❤️';
                if isprop(app, 'Estrella1_2') % Evitar error si no creaste los labels aún
                    app.Estrella1_2.Text = '☆'; app.Estrella2_2.Text = '☆'; app.Estrella3_2.Text = '☆';
                end

                app.SubirButton.Enable = 'on'; app.BajarButton.Enable = 'on';
                app.EvaluarButton.Enable = 'on';
            end
        end

        % Button pushed function: SIGUIENTENIVELButton
        function SIGUIENTENIVELButtonPushed(app, event)

            pestana_actual = app.TabGroup2.SelectedTab;
            tituloActual = strtrim(pestana_actual.Title);


            siguienteTitulo = '';
            switch tituloActual
                case 'Nivel 1', siguienteTitulo = 'Nivel 2';
                case 'Nivel 2', siguienteTitulo = 'Nivel 3';
                case 'Nivel 3', siguienteTitulo = 'Nivel 4';
            end

            if ~isempty(siguienteTitulo)


                pestanas = app.TabGroup2.Children;
                nuevaPestana = [];
                for i = 1:length(pestanas)
                    if strcmp(strtrim(pestanas(i).Title), siguienteTitulo)
                        nuevaPestana = pestanas(i);
                        break;
                    end
                end

                if ~isempty(nuevaPestana)

                    app.PermitirCambio = true;


                    app.TabGroup2.SelectedTab = nuevaPestana;
                    app.SIGUIENTENIVELButton.Enable = 'off';


                    eventoFalso = struct('OldValue', pestana_actual);
                    app.TabGroup2SelectionChanged(eventoFalso);
                end
            else
                app.alertaSciFi('SISTEMA COMPLETADO', '¡Misión cumplida! Has optimizado todos los reactores.');
            end
        end

        % Button pushed function: SIGUIENTENIVELButton_2
        function SIGUIENTENIVELButton_2Pushed(app, event)
            pestana_actual = app.TabGroup2_2.SelectedTab;
            tituloActual = strtrim(pestana_actual.Title);

            siguienteTitulo = '';
            switch tituloActual
                case 'Nivel 1', siguienteTitulo = 'Nivel 2';
                case 'Nivel 2', siguienteTitulo = 'Nivel 3';
                case 'Nivel 3', siguienteTitulo = 'Nivel 4';
            end

            if ~isempty(siguienteTitulo)
                pestanas = app.TabGroup2_2.Children;
                nuevaPestana = [];
                for i = 1:length(pestanas)
                    if strcmp(strtrim(pestanas(i).Title), siguienteTitulo)
                        nuevaPestana = pestanas(i);
                        break;
                    end
                end

                if ~isempty(nuevaPestana)
                    app.PermitirCambio = true;

                    app.TabGroup2_2.SelectedTab = nuevaPestana;
                    app.SIGUIENTENIVELButton_2.Enable = 'off';

                    eventoFalso = struct('OldValue', pestana_actual);
                    app.TabGroup2_2SelectionChanged(eventoFalso);
                end
            else
                app.alertaSciFi('SISTEMA COMPLETADO', '¡Misión cumplida! Has optimizado todas las secuencias.');
            end
        end

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Mdulo1Tab;
            app.alertaSciFi('SISTEMA EN LÍNEA', 'Bienvenido al Módulo 1. Calibre los reactores para detener al Dr. Caos.');
        end

        % Image clicked function: elegirheroe
        function elegirheroeImageClicked(app, event)

            app.PersonajeSeleccionado = 'Nixa';


            app.alertaSciFi('PERSONAJE CONFIRMADO', 'Has elegido a la heroína NIXA. Iniciando enlace neuronal con el Metaverso...');
            app.TabGroup.SelectedTab = app.IniciodelaHistoriaTab;

        end

        % Image clicked function: elegircientifico
        function elegircientificoImageClicked(app, event)

            app.PersonajeSeleccionado = 'Krakken';


            app.alertaSciFi('PERSONAJE CONFIRMADO', 'Has elegido al DR. KRAKKEN. Cargando protocolos de reactores químicos...');
            app.TabGroup.SelectedTab = app.IniciodelaHistoriaTab;
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Mdulo2Tab;
            app.alertaSciFi('MÓDULO 2 EN LÍNEA', 'Nuevos parámetros de cinética detectados. Optimice la secuencia de reactores.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PortadaTab = uitab(app.TabGroup);
            app.PortadaTab.Title = 'Portada';
            app.Image3 = uiimage(app.PortadaTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Reacciones del metroverso.png');
            app.elegirheroe = uiimage(app.PortadaTab);
            app.elegirheroe.ImageClickedFcn = createCallbackFcn(app, @elegirheroeImageClicked, true);
            app.elegirheroe.ImageSource = fullfile(pathToMLAPP, 'elegir_heroe_pulso.gif');
            app.elegircientifico = uiimage(app.PortadaTab);
            app.elegircientifico.ImageClickedFcn = createCallbackFcn(app, @elegircientificoImageClicked, true);
            app.elegircientifico.ImageSource = fullfile(pathToMLAPP, 'elegir_cientifico_pulso.gif');
            app.IniciodelaHistoriaTab = uitab(app.TabGroup);
            app.IniciodelaHistoriaTab.Title = 'Inicio de la Historia';
            app.TabGroup3 = uitabgroup(app.IniciodelaHistoriaTab);
            app.Pg1Tab = uitab(app.TabGroup3);
            app.Pg1Tab.Title = 'Pág. 1';
            app.Image = uiimage(app.Pg1Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.Pg2Tab = uitab(app.TabGroup3);
            app.Pg2Tab.Title = 'Pág. 2';
            app.Image2 = uiimage(app.Pg2Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '2.png');
            app.EmpezarButton = uibutton(app.Pg2Tab, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = 'Empezar';
            app.Mdulo1Tab = uitab(app.TabGroup);
            app.Mdulo1Tab.Title = 'Módulo 1 ';
            app.ouputpanel = uipanel(app.Mdulo1Tab);
            app.VOLMENESPanel = uipanel(app.ouputpanel);
            app.VOLMENESPanel.Title = 'VOLÚMENES';
            app.V1EditFieldLabel = uilabel(app.VOLMENESPanel);
            app.V1EditFieldLabel.Text = 'V1';
            app.V1EditField = uieditfield(app.VOLMENESPanel, 'numeric');
            app.RFPEditField_2Label = uilabel(app.VOLMENESPanel);
            app.RFPEditField_2Label.Text = 'RFP';
            app.RFPEditField_2 = uieditfield(app.VOLMENESPanel, 'numeric');
            app.V2EditFieldLabel = uilabel(app.VOLMENESPanel);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.VOLMENESPanel, 'numeric');
            app.V3EditFieldLabel = uilabel(app.VOLMENESPanel);
            app.V3EditFieldLabel.Text = 'V3';
            app.V3EditField = uieditfield(app.VOLMENESPanel, 'numeric');
            app.TotalEditFieldLabel = uilabel(app.VOLMENESPanel);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.VOLMENESPanel, 'numeric');
            app.V4EditFieldLabel = uilabel(app.VOLMENESPanel);
            app.V4EditFieldLabel.Text = 'V4';
            app.V4EditField = uieditfield(app.VOLMENESPanel, 'numeric');
            app.MensajeLabel = uilabel(app.ouputpanel);
            app.SIGUIENTENIVELButton = uibutton(app.ouputpanel, 'push');
            app.SIGUIENTENIVELButton.ButtonPushedFcn = createCallbackFcn(app, @SIGUIENTENIVELButtonPushed, true);
            app.SIGUIENTENIVELButton.Text = 'SIGUIENTE NIVEL';
            app.panelgraficos = uipanel(app.Mdulo1Tab);
            app.TabGroup2 = uitabgroup(app.panelgraficos);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.Nivel1Tab = uitab(app.TabGroup2);
            app.Nivel1Tab.Title = 'Nivel 1';
            app.Graficacaso1 = uiaxes(app.Nivel1Tab);
            title(app.Graficacaso1, 'Curva Levenspiel')
            xlabel(app.Graficacaso1, 'X')
            ylabel(app.Graficacaso1, 'Y')
            zlabel(app.Graficacaso1, 'Z')
            app.Nivel2Tab = uitab(app.TabGroup2);
            app.Nivel2Tab.Title = 'Nivel 2';
            app.Graficacaso2 = uiaxes(app.Nivel2Tab);
            title(app.Graficacaso2, 'Curva Levenspiel')
            xlabel(app.Graficacaso2, 'X')
            ylabel(app.Graficacaso2, 'Y')
            zlabel(app.Graficacaso2, 'Z')
            app.Nivel3Tab = uitab(app.TabGroup2);
            app.Nivel3Tab.Title = 'Nivel 3';
            app.Graficacaso3 = uiaxes(app.Nivel3Tab);
            title(app.Graficacaso3, 'Curva Levenspiel')
            xlabel(app.Graficacaso3, 'X')
            ylabel(app.Graficacaso3, 'Y')
            zlabel(app.Graficacaso3, 'Z')
            app.Nivel4Tab = uitab(app.TabGroup2);
            app.Nivel4Tab.Title = 'Nivel 4';
            app.Graficacaso4 = uiaxes(app.Nivel4Tab);
            title(app.Graficacaso4, 'Curva Levenspiel')
            xlabel(app.Graficacaso4, 'X')
            ylabel(app.Graficacaso4, 'Y')
            zlabel(app.Graficacaso4, 'Z')
            app.HighScoreLabel = uilabel(app.Mdulo1Tab);
            app.HighScoreLabel.Text = 'Label3';
            app.Gauge = uigauge(app.Mdulo1Tab, 'circular');
            app.Gauge.MajorTicks = [];
            app.Gauge.ScaleDirection = 'counterclockwise';
            app.Estrella2 = uilabel(app.Mdulo1Tab);
            app.Estrella2.Text = 'L1';
            app.Estrella1 = uilabel(app.Mdulo1Tab);
            app.Estrella1.Text = 'L2';
            app.Estrella3 = uilabel(app.Mdulo1Tab);
            app.Estrella3.Text = 'L3';
            app.inputpanel = uipanel(app.Mdulo1Tab);
            app.inputpanel.Title = 'CONFIGURACIÓN DE LOS REACTORES';
            app.ConversionesPanel = uipanel(app.inputpanel);
            app.ConversionesPanel.Title = 'Conversiones';
            app.ConversionesPanel.Visible = 'off';
            app.Sliderx1 = uislider(app.ConversionesPanel);
            app.Sliderx1.Limits = [0 1];
            app.Sliderx1.ValueChangedFcn = createCallbackFcn(app, @Sliderx1ValueChanged, true);
            app.Sliderx2 = uislider(app.ConversionesPanel);
            app.Sliderx2.Limits = [0 1];
            app.Sliderx2.ValueChangedFcn = createCallbackFcn(app, @Sliderx2ValueChanged, true);
            app.Sliderx3 = uislider(app.ConversionesPanel);
            app.Sliderx3.Limits = [0 1];
            app.Sliderx3.ValueChangedFcn = createCallbackFcn(app, @Sliderx3ValueChanged, true);
            app.EditFieldx1 = uieditfield(app.ConversionesPanel, 'numeric');
            app.EditFieldx1.ValueChangedFcn = createCallbackFcn(app, @EditFieldx1ValueChanged, true);
            app.EditFieldx2 = uieditfield(app.ConversionesPanel, 'numeric');
            app.EditFieldx2.ValueChangedFcn = createCallbackFcn(app, @EditFieldx2ValueChanged, true);
            app.EditFieldx3 = uieditfield(app.ConversionesPanel, 'numeric');
            app.EditFieldx3.ValueChangedFcn = createCallbackFcn(app, @EditFieldx3ValueChanged, true);
            app.x1Label = uilabel(app.ConversionesPanel);
            app.x1Label.Text = 'x1';
            app.x2Label = uilabel(app.ConversionesPanel);
            app.x2Label.Text = 'x2';
            app.x3Label = uilabel(app.ConversionesPanel);
            app.x3Label.Text = 'x3';
            app.EvaluarRMCsButton = uibutton(app.inputpanel, 'push');
            app.EvaluarRMCsButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluarRMCsButtonPushed, true);
            app.EvaluarRMCsButton.Text = 'Evaluar RMCs';
            app.EscojoRFPButton = uibutton(app.inputpanel, 'push');
            app.EscojoRFPButton.ButtonPushedFcn = createCallbackFcn(app, @EscojoRFPButtonPushed, true);
            app.EscojoRFPButton.Text = 'Escojo RFP';
            app.XobjetivoEditFieldLabel = uilabel(app.inputpanel);
            app.XobjetivoEditFieldLabel.Text = 'Xobjetivo';
            app.XobjetivoEditField = uieditfield(app.inputpanel, 'numeric');
            app.XobjetivoEditField.Editable = 'off';
            app.NroreactoresLabel = uilabel(app.inputpanel);
            app.NroreactoresLabel.Text = 'Número de reactores';
            app.NmerodereactoresDropDown = uidropdown(app.inputpanel);
            app.NmerodereactoresDropDown.Items = {'1', '2', '3', '4'};
            app.NmerodereactoresDropDown.ValueChangedFcn = createCallbackFcn(app, @NmerodereactoresDropDownValueChanged, true);
            app.NmerodereactoresDropDown.Value = '1';
            app.Label = uilabel(app.inputpanel);
            app.Label.Text = 'Fase';
            app.FaseSwitch = uiswitch(app.inputpanel, 'slider');
            app.FaseSwitch.Items = {'Líq.', 'Gas'};
            app.FaseSwitch.ValueChangedFcn = createCallbackFcn(app, @FaseSwitchValueChanged, true);
            app.FaseSwitch.Value = 'Líq.';
            app.COSTOSPanel = uipanel(app.Mdulo1Tab);
            app.COSTOSPanel.Title = 'COSTOS';
            app.RMCsEditFieldLabel = uilabel(app.COSTOSPanel);
            app.RMCsEditFieldLabel.Text = 'RMCs';
            app.RMCsEditField = uieditfield(app.COSTOSPanel, 'numeric');
            app.RFPEditFieldLabel = uilabel(app.COSTOSPanel);
            app.RFPEditFieldLabel.Text = 'RFP';
            app.RFPEditField = uieditfield(app.COSTOSPanel, 'numeric');
            app.ContinuacinTab = uitab(app.TabGroup);
            app.ContinuacinTab.Title = 'Continuación';
            app.TabGroup3_2 = uitabgroup(app.ContinuacinTab);
            app.Pg3Tab = uitab(app.TabGroup3_2);
            app.Pg3Tab.Title = 'Pág. 3';
            app.Image_2 = uiimage(app.Pg3Tab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '3.png');
            app.Pg4Tab = uitab(app.TabGroup3_2);
            app.Pg4Tab.Title = 'Pág. 4';
            app.Image2_2 = uiimage(app.Pg4Tab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, '4.png');
            app.SiguienteButton = uibutton(app.Pg4Tab, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.Mdulo2Tab = uitab(app.TabGroup);
            app.Mdulo2Tab.Title = 'Módulo 2 ';
            app.ENTRADAPanel = uipanel(app.Mdulo2Tab);
            app.ENTRADAPanel.Title = 'ENTRADA';
            app.OrdenReactoresListBoxLabel = uilabel(app.ENTRADAPanel);
            app.OrdenReactoresListBoxLabel.Text = 'Orden Reactores';
            app.OrdenReactoresListBox = uilistbox(app.ENTRADAPanel);
            app.OrdenReactoresListBox.Items = {'Reactor A 50 L', 'Reactor B 30 L'};
            app.OrdenReactoresListBox.ValueChangedFcn = createCallbackFcn(app, @OrdenReactoresListBoxValueChanged, true);
            app.OrdenReactoresListBox.Value = 'Reactor A 50 L';
            app.SubirButton = uibutton(app.ENTRADAPanel, 'push');
            app.SubirButton.ButtonPushedFcn = createCallbackFcn(app, @SubirButtonPushed, true);
            app.SubirButton.Text = 'Subir';
            app.BajarButton = uibutton(app.ENTRADAPanel, 'push');
            app.BajarButton.ButtonPushedFcn = createCallbackFcn(app, @BajarButtonPushed, true);
            app.BajarButton.Text = 'Bajar';
            app.FlujoVolumetricoEditFieldLabel = uilabel(app.ENTRADAPanel);
            app.FlujoVolumetricoEditFieldLabel.Text = 'Flujo Volumetrico';
            app.FlujoVolumetricoEditField = uieditfield(app.ENTRADAPanel, 'numeric');
            app.FlujoVolumetricoEditField.ValueChangedFcn = createCallbackFcn(app, @FlujoVolumetricoEditFieldValueChanged, true);
            app.SALIDAPanel = uipanel(app.Mdulo2Tab);
            app.SALIDAPanel.Title = 'SALIDA';
            app.ConversionAlcanzadaEditFieldLabel = uilabel(app.SALIDAPanel);
            app.ConversionAlcanzadaEditFieldLabel.Text = 'Conversion Alcanzada';
            app.ConversionAlcanzadaEditField = uieditfield(app.SALIDAPanel, 'numeric');
            app.ConversionAlcanzadaEditField.Editable = 'off';
            app.ExplicacionLabel = uilabel(app.SALIDAPanel);
            app.ExplicacionLabel.Text = 'Explicacion';
            app.Panel_6 = uipanel(app.Mdulo2Tab);
            app.TabGroup2_2 = uitabgroup(app.Panel_6);
            app.TabGroup2_2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2_2SelectionChanged, true);
            app.Nivel1Tab_2 = uitab(app.TabGroup2_2);
            app.Nivel1Tab_2.Title = 'Nivel 1';
            app.UIAxes_7 = uiaxes(app.Nivel1Tab_2);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.Nivel2Tab_2 = uitab(app.TabGroup2_2);
            app.Nivel2Tab_2.Title = 'Nivel 2';
            app.UIAxes_8 = uiaxes(app.Nivel2Tab_2);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.Nivel3Tab_2 = uitab(app.TabGroup2_2);
            app.Nivel3Tab_2.Title = 'Nivel 3';
            app.UIAxes_9 = uiaxes(app.Nivel3Tab_2);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.Nivel4Tab_2 = uitab(app.TabGroup2_2);
            app.Nivel4Tab_2.Title = 'Nivel 4';
            app.UIAxes_10 = uiaxes(app.Nivel4Tab_2);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.EvaluarButton = uibutton(app.Mdulo2Tab, 'push');
            app.EvaluarButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluarButtonPushed, true);
            app.EvaluarButton.Text = 'Evaluar';
            app.HighScoreLabel_2 = uilabel(app.Mdulo2Tab);
            app.HighScoreLabel_2.Text = 'Label3';
            app.SIGUIENTENIVELButton_2 = uibutton(app.Mdulo2Tab, 'push');
            app.SIGUIENTENIVELButton_2.ButtonPushedFcn = createCallbackFcn(app, @SIGUIENTENIVELButton_2Pushed, true);
            app.SIGUIENTENIVELButton_2.Text = 'SIGUIENTE NIVEL';
            app.FinTab = uitab(app.TabGroup);
            app.FinTab.Title = 'Fin';
            app.TabGroup3_3 = uitabgroup(app.FinTab);
            app.Pg5Tab = uitab(app.TabGroup3_3);
            app.Pg5Tab.Title = 'Pág. 5';
            app.Image_3 = uiimage(app.Pg5Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, '5.png');
            app.Pg6Tab = uitab(app.TabGroup3_3);
            app.Pg6Tab.Title = 'Pág. 6';
            app.Image2_3 = uiimage(app.Pg6Tab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, '6.png');
            app.Pg7Tab = uitab(app.TabGroup3_3);
            app.Pg7Tab.Title = 'Pág. 7';
            app.Image2_4 = uiimage(app.Pg7Tab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, '7.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Reactores_juego

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end